classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        Soal1Tab             matlab.ui.container.Tab
        srcEditField         matlab.ui.control.EditField
        srcEditFieldLabel    matlab.ui.control.Label
        PilihGambarButton    matlab.ui.control.Button
        UIAxes_Image         matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
        Soal2Tab             matlab.ui.container.Tab
        Soal3Tab             matlab.ui.container.Tab
        srcEditField3        matlab.ui.control.EditField
        srcEditField_2Label  matlab.ui.control.Label
        PilihGambarButton3   matlab.ui.control.Button
        UIAxes3_ImageIn      matlab.ui.control.UIAxes
        UIAxes3_ImageOut     matlab.ui.control.UIAxes
        UIAxes3_ImageOut2    matlab.ui.control.UIAxes
        hist3_in             matlab.ui.control.UIAxes
        hist3_out            matlab.ui.control.UIAxes
        hist3_matOut         matlab.ui.control.UIAxes
        Soal4Tab             matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
        function plot_histogram(~, ax, img)
            % PLOTHISTOGRAM Menampilkan histogram img pada suatu UiAxis
        
            cla(ax);
         
            [counts, binLocations] = image_histogram(img);

            % Use bar plot to display the histogram in the specified axes
            b = bar(ax, binLocations, counts); 
        
            if (size(counts,1) == 3)
                b(1).FaceColor = [1, 0, 0];
                b(2).FaceColor = [0, 1, 0];
                b(3).FaceColor = [0, 0, 1];
            end
            xlabel(ax,'Pixel Intensity');
            ylabel(ax, 'Count');
            title(ax, 'Histogram');
            legend(b, 'Red', 'Green', 'Blue');
        end

        function [img, fullPath] = browseImage(app)
           [file, path] = uigetfile('*.png;*.jpg;*.bmp;*.tif', 'Pilih Gambar');

            
            if isequal(file, 0)  % User clicked cancel
                img = [];        % Return empty image
                fullPath = '';   % Return empty path
            else
                fullPath = fullfile(path, file);  % Concatenate path and file
                img = imread(fullPath);            % Read the image file
            end

            drawnow;
            figure(app.UIFigure)

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PilihGambarButton
        function PilihGambarButtonPushed(app, event)
            [img, fullPath] = browseImage(app);
            app.srcEditField.Value = fullPath;
            imshow(img, 'Parent', app.UIAxes_Image);
            
            plot_histogram(app, app.UIAxes, img);
        end

        % Button pushed function: PilihGambarButton3
        function PilihGambarButton3Pushed(app, event)
            [img, fullPath] = browseImage(app);
            app.srcEditField3.Value = fullPath;
            out = hist_equalization(img);
            matOut = histeq(img);
            cla(app.UIAxes3_ImageIn, 'reset');
            cla(app.UIAxes3_ImageOut, 'reset');
            cla(app.UIAxes3_ImageOut2, 'reset');
            imshow(img, 'Parent', app.UIAxes3_ImageIn);
            imshow(out, 'Parent', app.UIAxes3_ImageOut);
            imshow(matOut, 'Parent', app.UIAxes3_ImageOut2);
            plot_histogram(app, app.hist3_in, img);
            plot_histogram(app, app.hist3_out, out);
            plot_histogram(app, app.hist3_matOut, matOut);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Soal1Tab = uitab(app.TabGroup);
            app.Soal1Tab.Title = 'Soal 1';
            app.UIAxes = uiaxes(app.Soal1Tab);
            title(app.UIAxes, 'Histogram')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_Image = uiaxes(app.Soal1Tab);
            title(app.UIAxes_Image, 'Gambar')
            app.UIAxes_Image.XTick = [0 0.5];
            app.UIAxes_Image.XTickLabel = '';
            app.UIAxes_Image.YTick = [];
            app.PilihGambarButton = uibutton(app.Soal1Tab, 'push');
            app.PilihGambarButton.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButtonPushed, true);
            app.PilihGambarButton.Text = 'Pilih Gambar';
            app.srcEditFieldLabel = uilabel(app.Soal1Tab);
            app.srcEditFieldLabel.Text = 'src';
            app.srcEditField = uieditfield(app.Soal1Tab, 'text');
            app.srcEditField.Editable = 'off';
            app.Soal2Tab = uitab(app.TabGroup);
            app.Soal2Tab.Title = 'Soal 2';
            app.Soal3Tab = uitab(app.TabGroup);
            app.Soal3Tab.Title = 'Soal 3';
            app.hist3_matOut = uiaxes(app.Soal3Tab);
            title(app.hist3_matOut, 'Histogram')
            app.hist3_out = uiaxes(app.Soal3Tab);
            title(app.hist3_out, 'Histogram')
            app.hist3_in = uiaxes(app.Soal3Tab);
            title(app.hist3_in, 'Histogram')
            app.UIAxes3_ImageOut2 = uiaxes(app.Soal3Tab);
            title(app.UIAxes3_ImageOut2, 'Matlab Output')
            app.UIAxes3_ImageOut2.XTick = [];
            app.UIAxes3_ImageOut2.XTickLabel = '';
            app.UIAxes3_ImageOut2.YTick = [];
            app.UIAxes3_ImageOut = uiaxes(app.Soal3Tab);
            title(app.UIAxes3_ImageOut, 'Output')
            app.UIAxes3_ImageOut.XTick = [0 0.5];
            app.UIAxes3_ImageOut.XTickLabel = '';
            app.UIAxes3_ImageOut.YTick = [];
            app.UIAxes3_ImageIn = uiaxes(app.Soal3Tab);
            title(app.UIAxes3_ImageIn, 'Input')
            app.UIAxes3_ImageIn.XTick = [];
            app.UIAxes3_ImageIn.YTick = [];
            app.PilihGambarButton3 = uibutton(app.Soal3Tab, 'push');
            app.PilihGambarButton3.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButton3Pushed, true);
            app.PilihGambarButton3.Text = 'Pilih Gambar';
            app.srcEditField_2Label = uilabel(app.Soal3Tab);
            app.srcEditField_2Label.Text = 'src';
            app.srcEditField3 = uieditfield(app.Soal3Tab, 'text');
            app.srcEditField3.Editable = 'off';
            app.Soal4Tab = uitab(app.TabGroup);
            app.Soal4Tab.Title = 'Soal 4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end